classdef RoBaristaGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        spawnobstacleCheckBox  matlab.ui.control.CheckBox
        DobotControlsLabel     matlab.ui.control.Label
        eStopButton            matlab.ui.control.StateButton
        eStopLamp              matlab.ui.control.Lamp
        processLog             matlab.ui.control.EditField
        pushbutton5            matlab.ui.control.Button
        edit10                 matlab.ui.control.EditField
        edit9                  matlab.ui.control.EditField
        edit7                  matlab.ui.control.EditField
        uibuttongroup9         matlab.ui.container.ButtonGroup
        edit6                  matlab.ui.control.EditField
        slider9                matlab.ui.control.Slider
        uibuttongroup8         matlab.ui.container.ButtonGroup
        edit5                  matlab.ui.control.EditField
        slider8                matlab.ui.control.Slider
        uibuttongroup7         matlab.ui.container.ButtonGroup
        edit4                  matlab.ui.control.EditField
        slider7                matlab.ui.control.Slider
        uibuttongroup6         matlab.ui.container.ButtonGroup
        edit3                  matlab.ui.control.EditField
        slider6                matlab.ui.control.Slider
        startProgram           matlab.ui.control.Button
        loadEnv                matlab.ui.control.Button
        axes1                  matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function slider1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RoBaristaGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to RoBaristaGUI (see VARARGIN)

            % Choose default command line output for RoBaristaGUI
            handles.output = hObject;


            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: loadEnv
        function loadEnvButtonPushed(app, event)
            % Spawn Environment with robots
            app.processLog.Value = "Loading Environment...";
            environment.spawnEnvironment()
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: eStopButton
        function eStopButtonValueChanged(app, event)
            value = app.eStopButton.Value;
            if value == 1
                app.eStopLamp.Color = "red";
                app.processLog.Value = "E-STOP ACTIVE";
            end
            if value == 0
                app.eStopLamp.Color = "green";
                app.processLog.Value = "System reset in progress...";

            end
        end

        % Button pushed function: startProgram
        function startProgramButtonPushed(app, event)
           if app.eStopLamp.Color == [0 1 0]
               app.processLog.Value = "RoBarista in Action";
               
           else
               app.processLog.Value = "E-STOP ACTIVE";
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RoBaristaGUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.loadEnv = uibutton(app.figure1, 'push');
            app.loadEnv.ButtonPushedFcn = createCallbackFcn(app, @loadEnvButtonPushed, true);
            app.loadEnv.Tag = 'loadEnv';
            app.loadEnv.Text = 'Load Environment';
            app.startProgram = uibutton(app.figure1, 'push');
            app.startProgram.ButtonPushedFcn = createCallbackFcn(app, @startProgramButtonPushed, true);
            app.startProgram.Tag = 'startProgram';
            app.startProgram.Text = 'Start Program';
            app.uibuttongroup6 = uibuttongroup(app.figure1);
            app.uibuttongroup6.Title = 'Base Joint (q1)';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.slider6 = uislider(app.uibuttongroup6);
            app.slider6.Limits = [0 1];
            app.slider6.MajorTicks = [];
            app.slider6.MinorTicks = [];
            app.slider6.Tag = 'slider6';
            app.edit3 = uieditfield(app.uibuttongroup6, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '0.0';
            app.uibuttongroup7 = uibuttongroup(app.figure1);
            app.uibuttongroup7.Title = 'q2';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.slider7 = uislider(app.uibuttongroup7);
            app.slider7.Limits = [0 1];
            app.slider7.MajorTicks = [];
            app.slider7.MinorTicks = [];
            app.slider7.Tag = 'slider7';
            app.edit4 = uieditfield(app.uibuttongroup7, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '0.0';
            app.uibuttongroup8 = uibuttongroup(app.figure1);
            app.uibuttongroup8.Title = 'q3';
            app.uibuttongroup8.Tag = 'uibuttongroup8';
            app.slider8 = uislider(app.uibuttongroup8);
            app.slider8.Limits = [0 1];
            app.slider8.MajorTicks = [];
            app.slider8.MinorTicks = [];
            app.slider8.Tag = 'slider8';
            app.edit5 = uieditfield(app.uibuttongroup8, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '0.0';
            app.uibuttongroup9 = uibuttongroup(app.figure1);
            app.uibuttongroup9.Title = 'q4';
            app.uibuttongroup9.Tag = 'uibuttongroup9';
            app.slider9 = uislider(app.uibuttongroup9);
            app.slider9.Limits = [0 1];
            app.slider9.MajorTicks = [];
            app.slider9.MinorTicks = [];
            app.slider9.Tag = 'slider9';
            app.edit6 = uieditfield(app.uibuttongroup9, 'text');
            app.edit6.Tag = 'edit6';
            app.edit6.Value = '0.0';
            app.edit7 = uieditfield(app.figure1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit9 = uieditfield(app.figure1, 'text');
            app.edit9.Tag = 'edit9';
            app.edit10 = uieditfield(app.figure1, 'text');
            app.edit10.Tag = 'edit10';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Move End Effector';
            app.processLog = uieditfield(app.figure1, 'text');
            app.eStopLamp = uilamp(app.figure1);
            app.eStopLamp.Tag = 'eStopLamp';
            app.eStopButton = uibutton(app.figure1, 'state');
            app.eStopButton.ValueChangedFcn = createCallbackFcn(app, @eStopButtonValueChanged, true);
            app.eStopButton.Tag = 'eStopButton';
            app.eStopButton.Icon = 'eStop.png';
            app.eStopButton.Text = '';
            app.DobotControlsLabel = uilabel(app.figure1);
            app.DobotControlsLabel.Text = 'Dobot Controls';
            app.spawnobstacleCheckBox = uicheckbox(app.figure1);
            app.spawnobstacleCheckBox.Tag = 'obstacleSpawnCheckbox';
            app.spawnobstacleCheckBox.Text = 'spawn obstacle';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoBaristaGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)RoBaristaGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end